classdef FrontPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        UpdatePlotButton               matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        PlotTypesButtonGroup           matlab.ui.container.ButtonGroup
        ContourPlottingButton          matlab.ui.control.ToggleButton
        LineFunctionButton             matlab.ui.control.ToggleButton
        OtherPlotsLabel                matlab.ui.control.Label
        SimulateSpectrumButton         matlab.ui.control.ToggleButton
        FortratDiagramButton           matlab.ui.control.ToggleButton
        HonlLondonFactorsButton        matlab.ui.control.ToggleButton
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup_2                     matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        GridLayout4_2                  matlab.ui.container.GridLayout
        GaussianFWHMnmEditField        matlab.ui.control.NumericEditField
        GaussianFWHMnmEditFieldLabel   matlab.ui.control.Label
        SimulatePlotsTab               matlab.ui.container.Tab
        GridLayout4_3                  matlab.ui.container.GridLayout
        MaxWavelengthnmEditField       matlab.ui.control.NumericEditField
        MaxWavelengthnmEditFieldLabel  matlab.ui.control.Label
        MinWavelengthnmEditField       matlab.ui.control.NumericEditField
        MinWavelengthnmEditFieldLabel  matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        SystemButton                   matlab.ui.control.RadioButton
        SingleBandButton               matlab.ui.control.RadioButton
        PlottingParametersLabel        matlab.ui.control.Label
        OtherParametersLabel           matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        TemperatureTab                 matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        VibrationalTemperatureKEditField  matlab.ui.control.EditField
        VibrationalTemperatureKLabel   matlab.ui.control.Label
        RotationalTempearatureKEditField  matlab.ui.control.NumericEditField
        RotationalTempearatureKLabel   matlab.ui.control.Label
        ElectronicTemperatureKEditField  matlab.ui.control.EditField
        ElectronicTemperatureKLabel    matlab.ui.control.Label
        SpectrumParamtersTab           matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        ofRotationalLinesJfEditField   matlab.ui.control.EditField
        ofRotationalLinesJfEditFieldLabel  matlab.ui.control.Label
        LowerVibrationalQuantumNumberV2EditField  matlab.ui.control.EditField
        LowerVibrationalQuantumNumberV2EditFieldLabel  matlab.ui.control.Label
        UpperVibrationalQuantumNumberV1EditField  matlab.ui.control.EditField
        UpperVibrationalQuantumNumberV_1Label  matlab.ui.control.Label
        SystemsButtonGroup             matlab.ui.container.ButtonGroup
        C2SwanButton                   matlab.ui.control.ToggleButton
        CNvioButton                    matlab.ui.control.ToggleButton
        SPSButton                      matlab.ui.control.ToggleButton
        FNSButton                      matlab.ui.control.ToggleButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function syst = systemGroup(app)
            swanButton  = app.C2SwanButton.Value;
            cnVioButton = app.CNvioButton.Value;
            fnsButton   = app.FNSButton.Value;
            spsButton   = app.SPSButton.Value;
            if fnsButton == 1
                syst = '1-';
            elseif spsButton == 1
                syst = '2+';
            elseif swanButton == 1
                syst = 'swan';
            elseif cnVioButton == 1
                syst = 'CNvio';
            end
        end

    end
    
    methods (Access = public)
        
        function [ax] = PlotType(app, syst, V1, V2, Jf, spec, temp)
            const = molecularConstants(syst, max([V1, V2]) );
            
            if app.SimulateSpectrumButton.Value == 1
                if app.SingleBandButton.Value == 1
                    [~, sim]		= simulateBand(app,syst, V1, V2, Jf, spec, temp, const);
                elseif app.SystemButton.Value == 1
                    [sim] = simulateSystem(app,syst, V1, V2, Jf, spec, temp);
                end
                plotData([], 'sim', sim);

            elseif app.ContourPlottingButton.Value == 1
                %%%%%%%%%% add plotting options 
            elseif app.HonlLondonFactorsButton.Value == 1
                [data, ~]		= simulateBand(app,syst, V1, V2, Jf, spec, temp, const);
                plotData(data, 'hl');
            elseif app.FortratDiagramButton.Value == 1
                [data, ~]		= simulateBand(app,syst, V1, V2, Jf, spec, temp, const);
                plotData(data,'fd');
            elseif app.LineFunctionButton == 1
                %%%%%%%%%% add plotting options 
            end            
            ax = gca;
           
        end

    	function [data, sim] = simulateBand(~,syst, V1, V2, Jf, spec, temp, const)
		    if nargin < 7
			    const = molecularConstants(syst, max([V1, V2]) );
		    end
		    data                = waveNumber(syst,V1,V2,Jf, const);
		    [data.Sp, data.Sq, data.Sr]  = honlLondon(data);
		    data.fc             = frankCondon(data);
		    [sim.wl, sim.i]     = simulateSpectrum(spec, data, temp);
        end
        
	    function [sim] = simulateSystem(~,syst, v1, v2, Jf, spec, temp) 	 		
            v1      = 0:v1;
            v2      = 0:v2;
		    [V1,V2] = meshgrid(v1,v2);
		    V1      = reshape(V1,[],1);
		    V2      = reshape(V2,[],1);
		    
		    I       = zeros(size(spec.wl));
		    
		    const 	= molecularConstants(syst, max([v1, v2]) );
		    for i   = 1:length(V1)
			    dataNew     = waveNumber(syst, V1(i), V2(i), Jf, const);
			    [dataNew.Sp, dataNew.Sq, dataNew.Sr]  = honlLondon(dataNew);
			    dataNew.fc  = frankCondon(dataNew);
			    [wl, inten]         = simulateSpectrum(spec, dataNew, temp);
			    I                   = I + inten;
		    end
		    
		    sim.wl  	= wl;
		    sim.i   	= I/max(I);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            %Define Plotting Options:
            set(0,'DefaultFigureVisible','off');  % all subsequent figures "off"
            cla(app.UIAxes);
            syst = systemGroup(app);
            
            V1   = str2double(app.UpperVibrationalQuantumNumberV1EditField.Value);
            V2   = str2double(app.LowerVibrationalQuantumNumberV2EditField.Value);
            Jf   = str2double(app.ofRotationalLinesJfEditField.Value);

            Tr  = app.RotationalTempearatureKEditField.Value;
            Tv  = str2double(app.VibrationalTemperatureKEditField.Value);
            Tel = str2double(app.ElectronicTemperatureKEditField.Value);

            temp.rot    = Tr;
            temp.vib    = Tv;
            temp.el     = Tel;

            spec.fwhm = 0.08;           		%fwhm of the spectrometer [nm]
            spec.res  = spec.fwhm/10;          	%spectrometer resolution (wavelenght/bin) [nm]
            spec.p    = 1;              		%relative magnitude of gaussian function.
            minWl     = app.MinWavelengthnmEditField.Value;
            maxWl     = app.MaxWavelengthnmEditField.Value;

            spec.wl   = (minWl:spec.res:maxWl)';    %wavelength range of interest.

            ax = PlotType(app, syst, V1, V2, Jf, spec, temp);
            copyobj(ax.Children, app.UIAxes);
            app.UIAxes.XLabel.String = ax.XLabel.String;
            app.UIAxes.YLabel.String = ax.YLabel.String;
            app.UIAxes.Title.String = ax.Title.String;
            app.UIAxes.XLim         = ax.XLim;
            app.UIAxes.YLim         = ax.YLim;
%             app.UIAxes.Legend   = ax.Title
            delete(ax);
            set(0,'DefaultFigureVisible','on');  % all subsequent figures "off"
        end

        % Callback function
        function SystemsButtonGroupSelectionChanged(app, event)

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            zlabel(app.UIAxes, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SystemsButtonGroup = uibuttongroup(app.GridLayout2);
            app.SystemsButtonGroup.Title = 'Systems';
            app.FNSButton = uitogglebutton(app.SystemsButtonGroup);
            app.FNSButton.Tag = '-1';
            app.FNSButton.Text = 'FNS';
            app.FNSButton.Value = true;
            app.SPSButton = uitogglebutton(app.SystemsButtonGroup);
            app.SPSButton.Tag = '2+';
            app.SPSButton.Text = 'SPS';
            app.CNvioButton = uitogglebutton(app.SystemsButtonGroup);
            app.CNvioButton.Tag = 'CNvio';
            app.CNvioButton.Text = 'CN vio';
            app.C2SwanButton = uitogglebutton(app.SystemsButtonGroup);
            app.C2SwanButton.Tag = 'C2 swan';
            app.C2SwanButton.Text = 'C2 Swan';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.TemperatureTab = uitab(app.TabGroup);
            app.TemperatureTab.Title = 'Temperature';
            app.GridLayout4 = uigridlayout(app.TemperatureTab);
            app.ElectronicTemperatureKLabel = uilabel(app.GridLayout4);
            app.ElectronicTemperatureKLabel.Text = 'Electronic Temperature (K):';
            app.ElectronicTemperatureKEditField = uieditfield(app.GridLayout4, 'text');
            app.ElectronicTemperatureKEditField.Value = '12000';
            app.RotationalTempearatureKLabel = uilabel(app.GridLayout4);
            app.RotationalTempearatureKLabel.Text = 'Rotational Tempearature (K):';
            app.RotationalTempearatureKEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RotationalTempearatureKEditField.Value = 3000;
            app.VibrationalTemperatureKLabel = uilabel(app.GridLayout4);
            app.VibrationalTemperatureKLabel.Text = 'Vibrational Temperature (K):';
            app.VibrationalTemperatureKEditField = uieditfield(app.GridLayout4, 'text');
            app.VibrationalTemperatureKEditField.Value = '3000';
            app.SpectrumParamtersTab = uitab(app.TabGroup);
            app.SpectrumParamtersTab.Title = 'Spectrum Paramters';
            app.GridLayout5 = uigridlayout(app.SpectrumParamtersTab);
            app.UpperVibrationalQuantumNumberV_1Label = uilabel(app.GridLayout5);
            app.UpperVibrationalQuantumNumberV_1Label.Text = 'Upper Vibrational Quantum Number (V1):';
            app.UpperVibrationalQuantumNumberV1EditField = uieditfield(app.GridLayout5, 'text');
            app.UpperVibrationalQuantumNumberV1EditField.Value = '4';
            app.LowerVibrationalQuantumNumberV2EditFieldLabel = uilabel(app.GridLayout5);
            app.LowerVibrationalQuantumNumberV2EditFieldLabel.Text = 'Lower Vibrational Quantum Number (V2):';
            app.LowerVibrationalQuantumNumberV2EditField = uieditfield(app.GridLayout5, 'text');
            app.LowerVibrationalQuantumNumberV2EditField.Value = '4';
            app.ofRotationalLinesJfEditFieldLabel = uilabel(app.GridLayout5);
            app.ofRotationalLinesJfEditFieldLabel.Text = '# of Rotational Lines (Jf):';
            app.ofRotationalLinesJfEditField = uieditfield(app.GridLayout5, 'text');
            app.ofRotationalLinesJfEditField.Value = '100';
            app.OtherParametersLabel = uilabel(app.GridLayout2);
            app.OtherParametersLabel.Text = 'Other Parameters:';
            app.PlottingParametersLabel = uilabel(app.GridLayout2);
            app.PlottingParametersLabel.Text = 'Plotting Parameters:';
            app.TabGroup_2 = uitabgroup(app.GridLayout2);
            app.GeneralTab = uitab(app.TabGroup_2);
            app.GeneralTab.Title = 'General';
            app.GridLayout4_2 = uigridlayout(app.GeneralTab);
            app.GaussianFWHMnmEditFieldLabel = uilabel(app.GridLayout4_2);
            app.GaussianFWHMnmEditFieldLabel.Text = 'Gaussian FWHM (nm):';
            app.GaussianFWHMnmEditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.GaussianFWHMnmEditField.Value = 0.1;
            app.SimulatePlotsTab = uitab(app.TabGroup_2);
            app.SimulatePlotsTab.Title = 'Simulate Plots';
            app.GridLayout4_3 = uigridlayout(app.SimulatePlotsTab);
            app.ButtonGroup = uibuttongroup(app.GridLayout4_3);
            app.SingleBandButton = uiradiobutton(app.ButtonGroup);
            app.SingleBandButton.Text = 'Single Band';
            app.SystemButton = uiradiobutton(app.ButtonGroup);
            app.SystemButton.Text = 'System';
            app.SystemButton.Value = true;
            app.MinWavelengthnmEditFieldLabel = uilabel(app.GridLayout4_3);
            app.MinWavelengthnmEditFieldLabel.Text = 'Min. Wavelength (nm):';
            app.MinWavelengthnmEditField = uieditfield(app.GridLayout4_3, 'numeric');
            app.MinWavelengthnmEditField.Value = 300;
            app.MaxWavelengthnmEditFieldLabel = uilabel(app.GridLayout4_3);
            app.MaxWavelengthnmEditFieldLabel.Text = 'Max. Wavelength (nm):';
            app.MaxWavelengthnmEditField = uieditfield(app.GridLayout4_3, 'numeric');
            app.MaxWavelengthnmEditField.Value = 520;
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.PlotTypesButtonGroup = uibuttongroup(app.GridLayout3);
            app.PlotTypesButtonGroup.Title = 'Plot Types';
            app.HonlLondonFactorsButton = uitogglebutton(app.PlotTypesButtonGroup);
            app.HonlLondonFactorsButton.Text = 'Honl-London Factors';
            app.FortratDiagramButton = uitogglebutton(app.PlotTypesButtonGroup);
            app.FortratDiagramButton.Text = 'Fortrat Diagram';
            app.SimulateSpectrumButton = uitogglebutton(app.PlotTypesButtonGroup);
            app.SimulateSpectrumButton.Text = 'Simulate Spectrum';
            app.SimulateSpectrumButton.Value = true;
            app.OtherPlotsLabel = uilabel(app.PlotTypesButtonGroup);
            app.OtherPlotsLabel.Text = 'Other Plots:';
            app.LineFunctionButton = uitogglebutton(app.PlotTypesButtonGroup);
            app.LineFunctionButton.Text = 'Line-Function';
            app.ContourPlottingButton = uitogglebutton(app.PlotTypesButtonGroup);
            app.ContourPlottingButton.Text = 'Contour-Plotting';
            app.UpdatePlotButton = uibutton(app.GridLayout, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrontPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end